classdef SynthesisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InitializationPanel         matlab.ui.container.Panel
        ControlMethodDropDownLabel  matlab.ui.control.Label
        ControlMethodDropDown       matlab.ui.control.DropDown
        StartDrawingButton          matlab.ui.control.Button
        SynthesisPanel              matlab.ui.container.Panel
        SynthesizeButton            matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        GridLayout                  matlab.ui.container.GridLayout
        mEditFieldLabel             matlab.ui.control.Label
        mEditField                  matlab.ui.control.NumericEditField
        nLabel                      matlab.ui.control.Label
        nEditField                  matlab.ui.control.NumericEditField
        EditField3Label             matlab.ui.control.Label
        EditField3                  matlab.ui.control.NumericEditField
        EditField4Label             matlab.ui.control.Label
        EditField4                  matlab.ui.control.NumericEditField
        SegmentButton               matlab.ui.control.Button
        GenerateCurveButton         matlab.ui.control.Button
        PlotAxes                    matlab.ui.control.UIAxes
        ModelSelectionPanel         matlab.ui.container.Panel
        LoadSTLFileLabel            matlab.ui.control.Label
        LoadFileButton              matlab.ui.control.Button
        STLDropDown                 matlab.ui.control.DropDown
        ClearSTLButton              matlab.ui.control.Button
    end

    
    properties (Access = public)
        % Curve
        curve;
        col = distinguishable_colors(10);
        arcLength = 20e-3;
        k = @(s,arcLength) 100 .* s/arcLength;
        
        % The STL file currently loaded
        stlFileName; % The name of the uploaded .STL file
        stlFilePath; % The path of the uploaded .STL file
        meMesh; % Middle-ear Mesh
        osMesh; % Ossicle Mesh
        totalMesh; % The total mesh of the inner ear
        transform; % Transform from Mesh to Base
        mePatch; % Middle ear STL patch
        osPatch; % Ossicle STL patch
        loadedSTLName;
    end
    
    methods (Access = private)
        
        % Search in the anatomical-models folder for all STL folders
        function findSTLFolders(app)
            files2 = dir("anatomical-models\");
            files2(1:2) = [];
            dirFlags = [files2.isdir];
            subFolders = files2(dirFlags);
            
            newDrop = {};
            
            % Iterate through all the subfolders and build the new dropdown
            for k = 1 : length(subFolders)
                newDrop(k) = cellstr(subFolders(k).name);
            end
            
            app.STLDropDown.Items = newDrop;
        end
        
        function resetCOMDropdown(app)
            COMports = seriallist;
            
            result = ["Keyboard"];
            for i = 1 : size(COMports, 2)
                result(i + 1) = COMports(i);
            end
            
            app.ControlMethodDropDown.Items = string(result);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            set(app.UIFigure, 'DoubleBuffer','on')    
            findSTLFolders(app)
            resetCOMDropdown(app)
        end

        % Callback function: LoadFileButton, STLDropDown
        function loadSTL(app, event)
            % Load an STL if the Load File button is pressed
            
            delete(app.mePatch);
            delete(app.osPatch);
            
            value = app.STLDropDown.Value;
            app.loadedSTLName = value;
            % fprintf("Loading: %s\n ", value);
            
            if strlength(value) > 0
                % Read the STL using Professor Fichera's code
                [meModel, transform, meModelRayCast, meSegmentation] = loadEarModel(value);
                
                % Store the values from the mesh
                app.transform = transform;
                % app.transform(1,4) = app.transform(1,4) + 10;
                app.meMesh = meModel.me;
                app.osMesh = meModel.os;
                
                % Construct a total mesh used for ray-casting
                points = vertcat(app.meMesh.Vertices, app.osMesh.Vertices);
                faceUpdate = app.osMesh.Faces + max(max(app.meMesh.Faces));
                faces = vertcat(app.meMesh.Faces, faceUpdate);
                
                app.totalMesh.Vertices = points;
                app.totalMesh.Faces = faces;
                
                % Draw the STLs
                app.mePatch = patch(app.PlotAxes, app.meMesh, 'FaceAlpha', 0.5, 'FaceColor', '#2b2e33', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                app.osPatch = patch(app.PlotAxes, app.osMesh, 'LineWidth', 0.2, 'FaceColor', '#071938', ...
                    'PickableParts', 'none', 'BusyAction', 'cancel');
                
                % Reset camera orientation and position and axis limits
                campos(app.PlotAxes, 'auto');
                xlim(app.PlotAxes, 'auto');
                ylim(app.PlotAxes, 'auto');
                zlim(app.PlotAxes, 'auto');
                axis(app.PlotAxes, 'equal');
            else
                disp("Illegal filename: Filename cannot be empty")
            end
        end

        % Button pushed function: ClearSTLButton
        function removeSTL(app, event)
            % Clear the axes
            delete(app.osPatch);
            delete(app.mePatch);
            
            % Reset all the parameters that were set before the load
            app.transform = eye(4);
            app.PlotAxes.XLim = [-15 15];
            app.PlotAxes.YLim = [-15 15];
            app.PlotAxes.ZLim = [0 15];
        end

        % Value changed function: ControlMethodDropDown
        function updateCOMDropdown(app, event)
            value = app.ControlMethodDropDown.Value;
            resetCOMDropdown(app); % Force an update of the dropdown
        end

        % Button pushed function: StartDrawingButton
        function connectArduino(app, event)
            app.keyBoard = 0;
            dropdownValue = app.ControlMethodDropDown.Value;
            
            if strcmp(dropdownValue, "Keyboard")
                app.keyBoard = 1;
                comME = MException("APPCONTROLLER:keyboardEnabled", "Keyboard enabled!\n");
                app.printError(comME);
                app.startWaitFlag = 1;
            else
                
                COMPort = app.ControlMethodDropDown.Value;
                
                % Ensure that the COM port is a number greater than 0
                if ~isempty(COMPort)
                    fprintf("Selected port: %s, Starting Arduino \n", COMPort);
                    try
                        app.arduinoController = arduinoController(COMPort);
                        app.startWaitFlag = 1;
                    catch
                        comME = MException("APPCONTROLLER:keyboardEnabled", "There was an error setting up the arduino\n Keyboard enabled!");
                        app.printError(comME);
                        app.keyBoard = 1;
                        app.startWaitFlag = 1;
                    end
                end
            end
        end

        % Button pushed function: GenerateCurveButton
        function generateCurve(app, event)
            offset = app.transform(1:3,4)
            app.curve = makecurve(app.arcLength, app.k, 'initial', offset);
            arc = app.curve.arc * 10e2;
            col = distinguishable_colors(10)
            scatter3(app.PlotAxes, arc(1,:), arc(2,:), arc(3,:), 'MarkerEdgeColor', col(5,:), 'LineWidth', 2.5);

            xlabel(app.PlotAxes, 'X [m]'), ylabel(app.PlotAxes, 'Y [m]'), zlabel(app.PlotAxes, 'Z [m]')
            xlim(app.PlotAxes, 'auto');
            ylim(app.PlotAxes, 'auto');
            zlim(app.PlotAxes, 'auto');
            axis(app.PlotAxes, 'equal');
        end

        % Key press function: UIFigure
        function windowKeyPress(app, event)
            key = event.Key;
            
            % Pass the callback through to another function
            
            % Execute appKeyPressCallBack.m
            key = event.Key;
            switch key
                case 'w'
                    % forward, W key is pressed
                    app.kMag = app.kMag + 1;
                case 's'
                    % backward, S key is pressed
                    app.kMag = app.kMag - 1;
                case 'a'
                    % left, A key is pressed
                    app.configuration(1) = app.configuration(1) + 0.05;
                case 'd'
                    % right, d key is pressed
                    app.configuration(1) = app.configuration(1) - 0.05;
                case 'q'
                    app.configuration(2) = app.configuration(2) + 0.1;
                case 'e'
                    app.configuration(2) = app.configuration(2) - 0.1;
            end
            
            generateCurve(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @windowKeyPress, true);
            app.InitializationPanel = uipanel(app.UIFigure);
            app.InitializationPanel.Title = 'Initialization';
            app.ControlMethodDropDownLabel = uilabel(app.InitializationPanel);
            app.ControlMethodDropDownLabel.Text = 'Control Method';
            app.ControlMethodDropDown = uidropdown(app.InitializationPanel);
            app.ControlMethodDropDown.Items = {'Keyboard', ''};
            app.ControlMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @updateCOMDropdown, true);
            app.ControlMethodDropDown.Value = 'Keyboard';
            app.StartDrawingButton = uibutton(app.InitializationPanel, 'push');
            app.StartDrawingButton.ButtonPushedFcn = createCallbackFcn(app, @connectArduino, true);
            app.StartDrawingButton.Text = 'Start Drawing';
            app.SynthesisPanel = uipanel(app.UIFigure);
            app.SynthesisPanel.Title = 'Synthesis';
            app.SynthesizeButton = uibutton(app.SynthesisPanel, 'push');
            app.SynthesizeButton.Text = 'Synthesize';
            app.Panel = uipanel(app.SynthesisPanel);
            app.GridLayout = uigridlayout(app.Panel);
            app.mEditFieldLabel = uilabel(app.GridLayout);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.GridLayout, 'numeric');
            app.nLabel = uilabel(app.GridLayout);
            app.nLabel.Text = 'n';
            app.nEditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField3Label = uilabel(app.GridLayout);
            app.EditField3Label.Text = 'Edit Field3';
            app.EditField3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField4Label = uilabel(app.GridLayout);
            app.EditField4Label.Text = 'Edit Field4';
            app.EditField4 = uieditfield(app.GridLayout, 'numeric');
            app.SegmentButton = uibutton(app.SynthesisPanel, 'push');
            app.SegmentButton.Text = 'Segment';
            app.GenerateCurveButton = uibutton(app.SynthesisPanel, 'push');
            app.GenerateCurveButton.ButtonPushedFcn = createCallbackFcn(app, @generateCurve, true);
            app.GenerateCurveButton.Text = 'Generate Curve';
            app.PlotAxes = uiaxes(app.UIFigure);
            title(app.PlotAxes, 'Curve Generator')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.ModelSelectionPanel = uipanel(app.UIFigure);
            app.ModelSelectionPanel.Title = 'Model Selection';
            app.LoadSTLFileLabel = uilabel(app.ModelSelectionPanel);
            app.LoadSTLFileLabel.Text = 'Load .STL File';
            app.LoadFileButton = uibutton(app.ModelSelectionPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSTL, true);
            app.LoadFileButton.Text = 'Load File';
            app.STLDropDown = uidropdown(app.ModelSelectionPanel);
            app.STLDropDown.Items = {'0'};
            app.STLDropDown.ValueChangedFcn = createCallbackFcn(app, @loadSTL, true);
            app.STLDropDown.Value = '0';
            app.ClearSTLButton = uibutton(app.ModelSelectionPanel, 'push');
            app.ClearSTLButton.ButtonPushedFcn = createCallbackFcn(app, @removeSTL, true);
            app.ClearSTLButton.Text = 'Clear STL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SynthesisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end